classdef DSP_Project2_AndreiD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WindowTypeButtonGroup        matlab.ui.container.ButtonGroup
        KaiserButton                 matlab.ui.control.RadioButton
        HammingButton                matlab.ui.control.RadioButton
        BlackmanHarrisButton         matlab.ui.control.RadioButton
        RectangularButton            matlab.ui.control.RadioButton
        FilterTypeButtonGroup        matlab.ui.container.ButtonGroup
        BandStopFilterButton         matlab.ui.control.RadioButton
        BandPassFilterButton         matlab.ui.control.RadioButton
        HighPassFilterButton         matlab.ui.control.RadioButton
        LowPassFilterButton          matlab.ui.control.RadioButton
        FileControlPanel             matlab.ui.container.Panel
        PlayFilteredButton           matlab.ui.control.Button
        PlayOriginalButton           matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        SaveButton                   matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        DSPProject1AudioFilterLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        filename;   % Name of input audio file
        input;      % Input audio file
        output;     % Input audio file with the desired effects applied
        Fs;         % Sampling frequency of the input audio file
        Ts;         % Sampling period of the input audio file
        t;          % Time in seconds of the input/output audio file
        t_final;    % Max value of the input audio file
    end
    
    methods (Access = private)
        
        % Disables Control Inputs based on the length of the sound
        function DisableControl(app, sound_length)
            
            % Disable file input fields until sound stops playing
            set(app.LoadButton, 'Enable', 'off');
            set(app.PlayOriginalButton, 'Enable', 'off');
            set(app.PlayFilteredButton, 'Enable', 'off');
            if strcmpi(app.LoadButton.Enable, 'off')
                pause(sound_length)
                set(app.LoadButton, 'Enable', 'on');
                set(app.PlayOriginalButton, 'Enable', 'on');
                set(app.PlayFilteredButton, 'Enable', 'on');
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % STARTUP FUNCTION 

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Store input file and setup file properties
            [app.filename,PathName,FilterIndex] = uigetfile('.wav');
            if isnumeric(app.filename)
                return;    %user canceled
            end
            [app.input,app.Fs] = audioread(app.filename);
            app.Ts = 1/app.Fs;
            app.t_final = length(app.input)/app.Fs;
            app.t = (0:app.Ts:app.t_final-app.Ts)';

            % Note: 1xN = Mono, 2xN = Stereo
            % If input is mono, make it stereo
            if isvector(app.input)
                app.input = [app.input app.input];
            end
           
            % Play and plot input signal
            sound(app.input,app.Fs);

            % Set the altered output sound file to input (temporary)
            app.output = app.input;

            % Dont input on file
            app.EditField.Value = app.filename;
            
            % Disables Control Inputs based on the length of the sound
            DisableControl(app, app.t_final);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                        
            [file,path] = uiputfile('*.wav', 'Save Wav File');
            if isnumeric(file)
                return;    %user canceled
            end

            % Check if the user didn put anything
            if isempty(file) == 1
                saveerror = errordlg('Enter File Name','Save Error');
            else
                filename = fullfile(path, file);
                audiowrite(filename, app.output, app.Fs);
            end

        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            
            % Play Altered Signal and Output on Graph
            % Note: Output one row only on a stereo signal
            sound(app.input, app.Fs)
            % plot(app.UIAxes_2, app.input) % nothing to plot

            % Disables Control Inputs based on the length of the sound
            DisableControl(app, app.t_final);

        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonPushed(app, event)
            
            % Play Altered Signal and Output on Graph
            % Note: Output one row only on a stereo signal
            sound(app.output, app.Fs)
            % plot(app.UIAxes_2, app.input) % nothing to plot

            % Disables Control Inputs based on the length of the sound
            DisableControl(app, app.t_final);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DSPProject1AudioFilterLabel = uilabel(app.UIFigure);
            app.DSPProject1AudioFilterLabel.Text = 'DSP Project 1 - Audio Filter ';
            app.FileControlPanel = uipanel(app.UIFigure);
            app.FileControlPanel.Title = 'File Control';
            app.LoadButton = uibutton(app.FileControlPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.FileControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.EditField = uieditfield(app.FileControlPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Placeholder = 'Input file name';
            app.PlayOriginalButton = uibutton(app.FileControlPanel, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayFilteredButton = uibutton(app.FileControlPanel, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonPushed, true);
            app.PlayFilteredButton.Text = 'Play Filtered';
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.LowPassFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.LowPassFilterButton.Text = 'Low Pass Filter';
            app.LowPassFilterButton.Value = true;
            app.HighPassFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.HighPassFilterButton.Text = 'High Pass Filter';
            app.BandPassFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandPassFilterButton.Text = 'Band Pass Filter';
            app.BandStopFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandStopFilterButton.Text = 'Band Stop Filter';
            app.WindowTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.WindowTypeButtonGroup.Title = 'Window Type';
            app.RectangularButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.BlackmanHarrisButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.BlackmanHarrisButton.Text = 'Blackman-Harris';
            app.HammingButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.KaiserButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.KaiserButton.Text = 'Kaiser';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_Project2_AndreiD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end